classdef TempoDynSys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        MIDIRecordingBPMEditFieldLabel  matlab.ui.control.Label
        MIDIRecordingBPMEditField       matlab.ui.control.NumericEditField
        TimeSignatureNumeratorEditFieldLabel  matlab.ui.control.Label
        TimeSignatureNumeratorEditField  matlab.ui.control.NumericEditField
        BrowseMIDIFileButton            matlab.ui.control.Button
        BrowseAudioFileButton           matlab.ui.control.Button
        NoMIDIFileSelectedLabel         matlab.ui.control.Label
        NoAudioFileSelectedLabel        matlab.ui.control.Label
        TimeSignatureHeadingLabel       matlab.ui.control.Label
        TimeSignatureLabel              matlab.ui.control.Label
        AnalyseCompareButton            matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        TempoAnalysisTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AnalysisTab                     matlab.ui.container.Tab
        GlobalBPMofAudioTrackLabel      matlab.ui.control.Label
        AudioGlobalBPMLabel             matlab.ui.control.Label
        MIDIGlobalBPMLabel              matlab.ui.control.Label
        GlobalBPMofMIDITrackLabel       matlab.ui.control.Label
        AudioTempoAxes                  matlab.ui.control.UIAxes
        MIDITempoAxes                   matlab.ui.control.UIAxes
        FeedbackTab                     matlab.ui.container.Tab
        TempoFeedbackTable              matlab.ui.control.Table
        NothinghasbeenanalysedyetLabel_2  matlab.ui.control.Label
        ActionSummaryLabel              matlab.ui.control.Label
        TempoTableLabel                 matlab.ui.control.Label
        DynamicsAnalysisTab             matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        AnalysisTab_2                   matlab.ui.container.Tab
        AudioDynamicsAxes               matlab.ui.control.UIAxes
        MIDIDynamicsAxes                matlab.ui.control.UIAxes
        FeedbackTab_2                   matlab.ui.container.Tab
        DynamicsFeedbackTable           matlab.ui.control.Table
        DynamicsTableLabel              matlab.ui.control.Label
        NothinghasbeenanalysedyetLabel  matlab.ui.control.Label
        ComebackhereonceyouhaveselectedyourclipsandLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        % Properties for setup tab and initial phase
        MIDIFilePath                % MIDI filepath for analysis
        AudioFilePath               % Audio filepath for analysis
        MIDIRecordingBPM            % BPM MIDI file was recorded at
        TimeSignatureNumerator      % Numerator of the time signature
        
        % Properties for temporal analysis
        AudioGlobalBPM              % Global BPM of the audio clip
        AudioLocalBPMs              % Local BPMs of the audio clip
        MIDIGlobalBPM               % Global BPM of the MIDI clip
        MIDILocalBPMs               % Local BPMs of the MIDI clip
        
        % Properties for length of other variables
        SegmentCount                % Number of segments in clips
        MIDIDuration                % Duration of MIDI clip in seconds
        AudioDuration               % Duration of audio clip in seconds
        
        % Properties for dynamics/intensity analysis
        AudioDynamics               % Dynamics labels of the audio clip
        AudioRMSDynamics            % RMS signal the audio clip
        MIDIDynamics                % Dynamics labels of the MIDI clip
        MIDIVelocities              % Mean velocities over the MIDI clip
        DynamicsLabels              % String array of dynamics labels
        
        % Properties for Plotting
        AudioTimeDomain             % Time domain axis for audio clip
        MIDITimeDomain              % Time domain axis for MIDI clip
        MIDIDynamicsIndices         % Indices for plotting MIDI section dynamics
        AudioDynamicsIndices        % Indices for plotting audio section dynamics
        
    end
    
    methods (Access = private)
        
        function PlotBPMs(app)
            
            tDomain = linspace(0, app.AudioDuration, length(app.AudioLocalBPMs));
            plot(app.AudioTempoAxes, tDomain, app.AudioLocalBPMs);
            yline(app.AudioTempoAxes, app.AudioGlobalBPM);
            ylim(app.AudioTempoAxes, [60, 200]);
            
            tDomain = linspace(0, app.MIDIDuration, length(app.MIDILocalBPMs));
            plot(app.MIDITempoAxes, tDomain, app.MIDILocalBPMs);
            yline(app.MIDITempoAxes, app.MIDIGlobalBPM);
            ylim(app.MIDITempoAxes, [60, 200]);
            
        end
        
        function PlotDynamics(app)
            
            tDomain = linspace(0, app.AudioDuration, length(app.AudioDynamics));
            plot(app.AudioDynamicsAxes, tDomain, app.AudioDynamicsIndices);
            ylim(app.AudioDynamicsAxes, [1, 6]);
            yticklabels(app.AudioDynamicsAxes, app.DynamicsLabels);
            
            % Plot the local MIDI dynamics
            tDomain = linspace(0, app.MIDIDuration, length(app.MIDIDynamics));
            plot(app.MIDIDynamicsAxes, tDomain, app.MIDIDynamicsIndices);
            ylim(app.MIDIDynamicsAxes, [1, 6]);
            yticklabels(app.MIDIDynamicsAxes, app.DynamicsLabels);
            
        end
        
        function PlotTempoTable(app, segmentIndices, bars, timeRanges, bpmDifferences)
            
            % Determine the action the user must take to match their
            % performance more closely with that of the reference track
            actions = strings(length(bpmDifferences), 1);
            for n=1:length(bpmDifferences)
                if bpmDifferences(n) < 0
                    actions(n) = "Speed Up";
                elseif bpmDifferences(n) > 0
                    actions(n) = "Slow Down";
                else
                    actions(n) = "No Change";
                end
            end
            
            % Set the data for the tempo feedback table
            app.TempoFeedbackTable.Data = [segmentIndices, bars, timeRanges, bpmDifferences + " BPM", actions];
            
        end
        
        function UpdateTempoSummaries(app, averageBpmDifference)

            % Determine whether the BPM was faster or slower on average and
            % then set up the summaries and set the UI label text
            tempoSummary = "On average, you played this piece " + num2str(abs(averageBpmDifference)) + " BPM";
            if averageBpmDifference > 0
                tempoSummary = tempoSummary + " faster than the reference track.";
                app.ActionSummaryLabel.Text = "Next time you play, try playing slower.";
            elseif averageBpmDifference < 0
                tempoSummary = tempoSummary + " slower than the reference track.";
                app.ActionSummaryLabel.Text = "Next time you play, try playing faster.";
            else
                tempoSummary = "On average, you played this piece at the exact same BPM as the reference track.";
                app.ActionSummaryLabel.Type = "Next time you play, try to do the same!";
            end
            app.NothinghasbeenanalysedyetLabel_2.Text = tempoSummary;
        end
        
        function PlotDynamicsTable(app, segmentIndices, bars, timeRanges, dynamicsDifferences)
            
            % Determine the action the user must take to match their
            % performances dynamics more closely with the reference track
            dynamicsActions = strings(length(dynamicsDifferences), 1);
            for n=1:length(dynamicsDifferences)
                if dynamicsDifferences(n) < 0
                    dynamicsActions(n) = "Play Louder";
                elseif dynamicsDifferences(n) > 0
                    dynamicsActions(n) = "Play Quieter";
                else
                    dynamicsActions(n) = "Play the Same";
                end
            end
            
            % Set the data for the dynamics feedback table
            app.DynamicsFeedbackTable.Data = [segmentIndices, bars, timeRanges, app.AudioDynamics, app.MIDIDynamics, dynamicsActions];
            
        end
        
        function UpdateDynamicsSummaries(app, averageDynamicsDifference)
            if averageDynamicsDifference > 0
                app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece louder than the reference track.";
                app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try playing more softly.";
            elseif averageDynamicsDifference < 0
                app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece softer than the reference track.";
                app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try playing louder.";
            else
                app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece with the same dynamics as the reference track.";
                app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try to play the same!";
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MIDIRecordingBPM = 120;
            app.TimeSignatureNumerator = 4;
            app.AnalyseCompareButton.Enable = false;
            app.MIDIFilePath = 0;
            app.AudioFilePath = 0;
            app.DynamicsLabels = ["pp", "p", "mp", "mf", "f", "ff"];
        end

        % Button pushed function: BrowseMIDIFileButton
        function BrowseMIDIFileButtonPushed(app, event)
            [filename, filepath, ~] = uigetfile(".mid", "Open MIDI file for analysis...");
            if filename == 0 & filepath == 0
                return;
            end
            app.MIDIFilePath = strcat(filepath, filename);
            app.NoMIDIFileSelectedLabel.Text = filename;
            if app.AudioFilePath ~= 0
                app.AnalyseCompareButton.Enable = true;
            end
        end

        % Button pushed function: BrowseAudioFileButton
        function BrowseAudioFileButtonPushed(app, event)
            [filename, filepath, ~] = uigetfile(".wav", "Open audio file for analysis...");
            if filename == 0 & filepath == 0
                return;
            end
            app.AudioFilePath = strcat(filepath, filename);
            app.NoAudioFileSelectedLabel.Text = filename;
            if app.MIDIFilePath ~= 0
                app.AnalyseCompareButton.Enable = true;
            end
        end

        % Value changed function: TimeSignatureNumeratorEditField
        function TimeSignatureNumeratorEditFieldValueChanged(app, event)
            value = app.TimeSignatureNumeratorEditField.Value;
            app.TimeSignatureNumerator = value;
            app.TimeSignatureLabel.Text = value + "/4";
        end

        % Button pushed function: AnalyseCompareButton
        function AnalyseCompareButtonPushed(app, event)
            
            % Read the MIDI data from the MIDI file
            midiMatrix = readmidi(app.MIDIFilePath);
            
            % Read the audio file and get the sampling frequency
            [audio, Fs] = audioread(app.AudioFilePath);
            
            % Convert the audio to mono, normalize it and crop silence from
            % the start and finish
            audio = monoconvert(audio);
            audio = audio / max(abs(audio));
            audio = cropsilence(audio, 2e-3);
            
            app.AudioDuration = length(audio) / Fs;
            
            % Get the global and local BPMs of the audio clip
            [app.AudioGlobalBPM, app.AudioLocalBPMs] = getaudiobpms(audio, Fs, app.TimeSignatureNumerator);
            
            % Number of segments in the audio
            % 1 segment = approx 2 bars
            app.SegmentCount = length(app.AudioLocalBPMs);
            
            % Extract the MIDI onsets
            % This used to also be used for getting the global MIDI BPM but
            % the approach was changed due to inaccuracies
            [~, onsets, ~] = getglobalmidibpm(midiMatrix, app.MIDIRecordingBPM, app.TimeSignatureNumerator);
            
            synthesizedMidi = nmat2snd(midiMatrix, 'fm', Fs)';
            synthesizedMidi = synthesizedMidi / max(abs(synthesizedMidi));
            synthesizedMidi = cropsilence(synthesizedMidi, 2e-3);
            [app.MIDIGlobalBPM, app.MIDILocalBPMs] = getaudiobpms(synthesizedMidi, Fs, app.TimeSignatureNumerator);
            
            % Calculate the duration of the MIDI clip and adjust for
            % assumed 120 BPM tempo used by MIDI Toolbox
            app.MIDIDuration = (length(synthesizedMidi) / Fs) * (120 / app.MIDIRecordingBPM);
            
            % Calculate the RMS values across the audio clip
            audioRms = getrmsdynamics(audio, Fs, 500e-3);
            % Take the average of the RMS values over each segment of audio
            app.AudioRMSDynamics = getlocaldynamics(audioRms, length(audio), Fs, app.AudioGlobalBPM, app.TimeSignatureNumerator);
            
            % Match the audio dynamics segments to the BPM segments
            app.AudioRMSDynamics = matchsegments(app.AudioRMSDynamics, app.SegmentCount, app.AudioDuration);
            
            % Conver the RMS values to dynamics labels
            [app.AudioDynamics, app.AudioDynamicsIndices] = convertrmstolabels(app.AudioRMSDynamics);
            
            % Extract the mean velocities of the MIDI notes over each section
            app.MIDIVelocities = getlocalvelocities(midiMatrix, onsets, app.MIDIGlobalBPM, app.TimeSignatureNumerator);
            
            % Convert the mean velocities to dynamics labels
            [app.MIDIDynamics, app.MIDIDynamicsIndices] = convertvelocitytolabels(app.MIDIVelocities);
            
            % Fix the length of the MIDI analysis values into the same
            % number of segments as the audio analysis ready for comparison
            app.MIDILocalBPMs = matchsegments(app.MIDILocalBPMs, app.SegmentCount, app.AudioDuration);
            app.MIDIDynamicsIndices = matchsegments(app.MIDIDynamicsIndices, app.SegmentCount, app.AudioDuration);
            app.MIDIDynamics = app.DynamicsLabels(app.MIDIDynamicsIndices)';
            
            % Update global BPM label text
            app.AudioGlobalBPMLabel.Text = num2str(app.AudioGlobalBPM);
            app.MIDIGlobalBPMLabel.Text = num2str(app.MIDIGlobalBPM);
            
            % Automatically select the tempo tab
            app.TabGroup.SelectedTab = app.TempoAnalysisTab;
            
            % Plot the local audio BPMs
%             tDomain2 = linspace(0, app.AudioDuration, length(app.AudioLocalBPMs));
%             plot(app.AudioTempoAxes, tDomain2, app.AudioLocalBPMs);
%             yline(app.AudioTempoAxes, app.AudioGlobalBPM);
%             ylim(app.AudioTempoAxes, [60, 200]);
            
            % Plot the local MIDI BPMs
%             tDomain3 = linspace(0, app.MIDIDuration, length(app.MIDILocalBPMs));
%             plot(app.MIDITempoAxes, tDomain3, app.MIDILocalBPMs);
%             yline(app.MIDITempoAxes, app.MIDIGlobalBPM);
%             ylim(app.MIDITempoAxes, [60, 200]);
            
            % PLot the local BPMs for the audio and MIDI clips
            PlotBPMs(app);
            
            % Plot the local audio dynamics
%             tDomain4 = linspace(0, app.AudioDuration, length(app.AudioDynamics));
%             plot(app.AudioDynamicsAxes, tDomain4, app.AudioDynamicsIndices);
%             ylim(app.AudioDynamicsAxes, [1, 6]);
%             yticklabels(app.AudioDynamicsAxes, app.DynamicsLabels);
%             
%             % Plot the local MIDI dynamics
%             tDomain5 = linspace(0, app.MIDIDuration, length(app.MIDIDynamics));
%             plot(app.MIDIDynamicsAxes, tDomain5, app.MIDIDynamicsIndices);
%             ylim(app.MIDIDynamicsAxes, [1, 6]);
%             yticklabels(app.MIDIDynamicsAxes, app.DynamicsLabels);
            
            % Plot the dynamics for the audio and MIDI clips
            PlotDynamics(app);
            
            % Define the start and end times in seconds for each segment
            startPoints = linspace(0, app.AudioDuration, length(app.MIDILocalBPMs));
            endPoints = startPoints(2:end);
            endPoints(end + 1) = startPoints(end) + (endPoints(2) - endPoints(1));
            
            % Define shared table column data
            segmentIndices = (1:length(app.MIDILocalBPMs))';
            barStarts = (((1:length(app.MIDILocalBPMs))' - 1) * 2) + 1;
            barEnds = barStarts + 1;
            timeRangeColumn = (startPoints + "s - " + endPoints + "s")';
            bars = barStarts + " - " + barEnds;
            
%             % Calculate the differences in BPM for each segment
%             bpmDifferences = app.MIDILocalBPMs - app.AudioLocalBPMs;
%             % Determine the action the user must take to match their
%             % performance more closely with that of the reference track
%             actions = strings(length(bpmDifferences), 1);
%             for n=1:length(bpmDifferences)
%                 if bpmDifferences(n) < 0
%                     actions(n) = "Speed Up";
%                 elseif bpmDifferences(n) > 0
%                     actions(n) = "Slow Down";
%                 else
%                     actions(n) = "No Change";
%                 end
%             end
%             
%             % Set the data for the tempo feedback table
%             app.TempoFeedbackTable.Data = [segmentIndices, barStarts + " - " + barEnds, timeRangeColumn, bpmDifferences + " BPM", actions];
            
            % Calculate the differences in BPM for each segment
            bpmDifferences = app.MIDILocalBPMs - app.AudioLocalBPMs;

            PlotTempoTable(app, segmentIndices, bars, timeRangeColumn, bpmDifferences);

            % Calculate the average difference between the BPMs in each
            % segment
            averageDifference = mean(bpmDifferences);
%             
%             % Determine whether the BPM was faster or slower on average and
%             % then set up the summaries and set the UI label text
%             tempoSummary = "On average, you played this piece " + num2str(abs(averageDifference)) + " BPM";
%             if averageDifference > 0
%                 tempoSummary = tempoSummary + " faster than the reference track.";
%                 app.ActionSummaryLabel.Text = "Next time you play, try playing slower.";
%             elseif averageDifference < 0
%                 tempoSummary = tempoSummary + " slower than the reference track.";
%                 app.ActionSummaryLabel.Text = "Next time you play, try playing faster.";
%             else
%                 tempoSummary = "On average, you played this piece at the exact same BPM as the reference track.";
%                 app.ActionSummaryLabel.Type = "Next time you play, try to do the same!";
%             end
%             app.NothinghasbeenanalysedyetLabel_2.Text = tempoSummary;
             
            UpdateTempoSummaries(app, averageDifference);

            % Calculate the differences in dynamics for each segment
            dynamicsDifferences = app.MIDIDynamicsIndices - app.AudioDynamicsIndices;
            
            PlotDynamicsTable(app, segmentIndices, bars, timeRangeColumn, dynamicsDifferences);
            
%             % Determine the action the user must take to match their
%             % performances dynamics more closely with the reference track
%             dynamicsActions = strings(length(dynamicsDifferences), 1);
%             for n=1:length(dynamicsDifferences)
%                 if dynamicsDifferences(n) < 0
%                     dynamicsActions(n) = "Play Louder";
%                 elseif dynamicsDifferences(n) > 0
%                     dynamicsActions(n) = "Play Quieter";
%                 else
%                     dynamicsActions(n) = "Play the Same";
%                 end
%             end
%             
%             % Set the data for the dynamics feedback table
%             app.DynamicsFeedbackTable.Data = [segmentIndices, barStarts + " - " + barEnds, timeRangeColumn, app.AudioDynamics, app.MIDIDynamics, dynamicsActions];
            
            % Get the most common difference in dynamics and check if the
            % piece should be played more loudly or more softly and then
            % update the UI labels
            averageDynamicsDifference = mode(dynamicsDifferences);
            
            UpdateDynamicsSummaries(app, averageDynamicsDifference);
            
%             if averageDynamicsDifference > 0
%                 app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece louder than the reference track.";
%                 app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try playing more softly.";
%             elseif averageDynamicsDifference < 0
%                 app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece softer than the reference track.";
%                 app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try playing louder.";
%             else
%                 app.NothinghasbeenanalysedyetLabel.Text = "On average, you played this piece with the same dynamics as the reference track.";
%                 app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = "Next time you play, try to play the same!";
%             end
            
        end

        % Value changed function: MIDIRecordingBPMEditField
        function MIDIRecordingBPMEditFieldValueChanged(app, event)
            value = app.MIDIRecordingBPMEditField.Value;
            app.MIDIRecordingBPM = value;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            HelpPage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.MIDIRecordingBPMEditFieldLabel = uilabel(app.SetupTab);
            app.MIDIRecordingBPMEditFieldLabel.Text = '    MIDI Recording BPM   ';
            app.MIDIRecordingBPMEditField = uieditfield(app.SetupTab, 'numeric');
            app.MIDIRecordingBPMEditField.ValueChangedFcn = createCallbackFcn(app, @MIDIRecordingBPMEditFieldValueChanged, true);
            app.MIDIRecordingBPMEditField.Value = 120;
            app.TimeSignatureNumeratorEditFieldLabel = uilabel(app.SetupTab);
            app.TimeSignatureNumeratorEditFieldLabel.Text = 'Time Signature Numerator';
            app.TimeSignatureNumeratorEditField = uieditfield(app.SetupTab, 'numeric');
            app.TimeSignatureNumeratorEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSignatureNumeratorEditFieldValueChanged, true);
            app.TimeSignatureNumeratorEditField.Value = 4;
            app.BrowseMIDIFileButton = uibutton(app.SetupTab, 'push');
            app.BrowseMIDIFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMIDIFileButtonPushed, true);
            app.BrowseMIDIFileButton.Text = 'Browse MIDI File';
            app.BrowseAudioFileButton = uibutton(app.SetupTab, 'push');
            app.BrowseAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAudioFileButtonPushed, true);
            app.BrowseAudioFileButton.Text = 'Browse Audio File';
            app.NoMIDIFileSelectedLabel = uilabel(app.SetupTab);
            app.NoMIDIFileSelectedLabel.Text = 'No MIDI File Selected';
            app.NoAudioFileSelectedLabel = uilabel(app.SetupTab);
            app.NoAudioFileSelectedLabel.Text = 'No Audio File Selected';
            app.TimeSignatureHeadingLabel = uilabel(app.SetupTab);
            app.TimeSignatureHeadingLabel.Text = 'Time Signature';
            app.TimeSignatureLabel = uilabel(app.SetupTab);
            app.TimeSignatureLabel.Text = '4/4';
            app.AnalyseCompareButton = uibutton(app.SetupTab, 'push');
            app.AnalyseCompareButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseCompareButtonPushed, true);
            app.AnalyseCompareButton.Text = 'Analyse & Compare';
            app.HelpButton = uibutton(app.SetupTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.TempoAnalysisTab = uitab(app.TabGroup);
            app.TempoAnalysisTab.Title = 'Tempo Analysis';
            app.TabGroup2 = uitabgroup(app.TempoAnalysisTab);
            app.AnalysisTab = uitab(app.TabGroup2);
            app.AnalysisTab.Title = 'Analysis';
            app.GlobalBPMofAudioTrackLabel = uilabel(app.AnalysisTab);
            app.GlobalBPMofAudioTrackLabel.Text = 'Global BPM of Audio Track: ';
            app.AudioGlobalBPMLabel = uilabel(app.AnalysisTab);
            app.AudioGlobalBPMLabel.Text = '120';
            app.MIDIGlobalBPMLabel = uilabel(app.AnalysisTab);
            app.MIDIGlobalBPMLabel.Text = '120';
            app.GlobalBPMofMIDITrackLabel = uilabel(app.AnalysisTab);
            app.GlobalBPMofMIDITrackLabel.Text = 'Global BPM of MIDI Track: ';
            app.AudioTempoAxes = uiaxes(app.AnalysisTab);
            title(app.AudioTempoAxes, 'Audio Track BPM Over Time')
            xlabel(app.AudioTempoAxes, 'Time (s)')
            ylabel(app.AudioTempoAxes, 'Tempo (BPM)')
            zlabel(app.AudioTempoAxes, 'Z')
            app.MIDITempoAxes = uiaxes(app.AnalysisTab);
            title(app.MIDITempoAxes, 'MIDI Track BPM Over Time')
            xlabel(app.MIDITempoAxes, 'Time (s)')
            ylabel(app.MIDITempoAxes, 'Tempo (BPM)')
            zlabel(app.MIDITempoAxes, 'Z')
            app.FeedbackTab = uitab(app.TabGroup2);
            app.FeedbackTab.Title = 'Feedback';
            app.TempoFeedbackTable = uitable(app.FeedbackTab);
            app.TempoFeedbackTable.ColumnName = {'Segment'; 'Estimated Bars'; 'Time'; 'Tempo Difference'; 'Action'};
            app.TempoFeedbackTable.RowName = {};
            app.NothinghasbeenanalysedyetLabel_2 = uilabel(app.FeedbackTab);
            app.NothinghasbeenanalysedyetLabel_2.Text = 'Nothing has been analysed yet!';
            app.ActionSummaryLabel = uilabel(app.FeedbackTab);
            app.ActionSummaryLabel.Text = 'Come back here once you have selected your clips and clicked "Analyse & Compare" in the Setup tab.';
            app.TempoTableLabel = uilabel(app.FeedbackTab);
            app.TempoTableLabel.Text = 'Below is a table showing the tempo differences throughout 2 bar segments of the performance.';
            app.DynamicsAnalysisTab = uitab(app.TabGroup);
            app.DynamicsAnalysisTab.Title = 'Dynamics Analysis';
            app.TabGroup3 = uitabgroup(app.DynamicsAnalysisTab);
            app.AnalysisTab_2 = uitab(app.TabGroup3);
            app.AnalysisTab_2.Title = 'Analysis';
            app.AudioDynamicsAxes = uiaxes(app.AnalysisTab_2);
            title(app.AudioDynamicsAxes, 'Audio Track Dynamics Over Time')
            xlabel(app.AudioDynamicsAxes, 'Time (s)')
            ylabel(app.AudioDynamicsAxes, 'Dynamics')
            zlabel(app.AudioDynamicsAxes, 'Z')
            app.MIDIDynamicsAxes = uiaxes(app.AnalysisTab_2);
            title(app.MIDIDynamicsAxes, 'MIDI Track Dynamics Over Time')
            xlabel(app.MIDIDynamicsAxes, 'Time (s)')
            ylabel(app.MIDIDynamicsAxes, 'Dynamics')
            zlabel(app.MIDIDynamicsAxes, 'Z')
            app.FeedbackTab_2 = uitab(app.TabGroup3);
            app.FeedbackTab_2.Title = 'Feedback';
            app.DynamicsFeedbackTable = uitable(app.FeedbackTab_2);
            app.DynamicsFeedbackTable.ColumnName = {'Segment'; 'Estimated Bars'; 'Time'; 'Audio Dynamics'; 'MIDI Dynamics'; 'Action'};
            app.DynamicsFeedbackTable.RowName = {};
            app.DynamicsTableLabel = uilabel(app.FeedbackTab_2);
            app.DynamicsTableLabel.Text = 'Below is a table showing the differences in dynamics throughout 2 bar segments of the performance.';
            app.NothinghasbeenanalysedyetLabel = uilabel(app.FeedbackTab_2);
            app.NothinghasbeenanalysedyetLabel.Text = 'Nothing has been analysed yet!';
            app.ComebackhereonceyouhaveselectedyourclipsandLabel = uilabel(app.FeedbackTab_2);
            app.ComebackhereonceyouhaveselectedyourclipsandLabel.Text = 'Come back here once you have selected your clips and clicked "Analyse & Compare" in the Setup tab.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TempoDynSys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end